classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        InstructionsTextArea         matlab.ui.control.TextArea
        InstructionsTextAreaLabel    matlab.ui.control.Label
        Label2                       matlab.ui.control.Label
        DisplaysaptakButton          matlab.ui.control.Button
        DisplaynameofnoteButton      matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        BaseFrequencyEditField       matlab.ui.control.NumericEditField
        BaseFrequencyEditFieldLabel  matlab.ui.control.Label
        PlayRandomnoteButton         matlab.ui.control.Button
        PlayShadajButton             matlab.ui.control.Button
        GeneratearandomnoteButton    matlab.ui.control.Button
        GenerateShadajButton         matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateShadajButton
        function GenerateShadajButtonPushed(app, event)
            f = app.BaseFrequencyEditField.Value;
            f = f/ 2;
            global s;
            global fS_L;
            [s,   fS_L]  = contour(2, 0, 1, f);
        end

        % Button pushed function: PlayShadajButton
        function PlayShadajButtonPushed(app, event)
             global s;
             sound(s, 22520);
        end

        % Button pushed function: GeneratearandomnoteButton
        function GeneratearandomnoteButtonPushed(app, event)
            global note Name SaptakName fnote;

            f = app.BaseFrequencyEditField.Value;
            f = f / 2;
            n = 0:11;
            size = length(n);
            n1 = randi([1, size]);
            saptak = randi([1, 3]); 
            [note, fnote] = contour(saptak, n1, 1, f);
            
            switch n1
                case 1
                    Name = 'Shadaj';
                case 2
                    Name = 'Komal Rishabha';
                case 3
                    Name = 'Rishabha';
                case 4
                    Name = 'Komal Gandhara';
                case 5
                    Name = 'Gandhara';
                case 6
                    Name = 'Shuddha Madhyama';
                case 7
                    Name = 'Teevra Madhyama';
                case 8
                    Name = 'Panchama';
                case 9
                    Name = 'Komal Dhaivata';
                case 10
                    Name = 'Dhaivata';
                case 11
                    Name = 'Komal Nishada';
                case 12
                    Name = 'Nishada';
            end

            if saptak == 1
                SaptakName = 'Mandra Saptak'; % Lower octave
            elseif saptak == 2
                SaptakName = 'Madhya Saptak'; % Middle octave
            else
                SaptakName = 'Taar Saptak'; % Higher octave
            end   
    
        end

        % Button pushed function: PlayRandomnoteButton
        function PlayRandomnoteButtonPushed(app, event)
            global note;
            sound(note, 22520);
            UIAxesButtonDown(app, event);
        end

        % Button pushed function: DisplaynameofnoteButton
        function DisplaynameofnoteButtonPushed(app, event)
            global Name;
            app.Label.Text = Name;
        end

        % Button pushed function: DisplaysaptakButton
        function DisplaysaptakButtonPushed(app, event)
            global SaptakName;
            app.Label2.Text = SaptakName;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            global fS_L fnote;
            if isempty(fS_L) || isempty(fnote)
                    fnote
                    disp('fS_L or ftone is not defined.');
                    return;
                end
            % Data points to plot
            % Assuming fS_L and fnote are already defined
            
            x = [0, 0 ,fS_L, 0, 0, 0]; 
            y = [0, 0 ,0 fnote, 0 ,0]; 
            
            % Clear the axes before plotting
            cla(app.UIAxes);
            
            % Plotting on the UIAxes in green
            stem(app.UIAxes, x, 'g', 'LineWidth', 2); 
            hold(app.UIAxes, 'on');
            
            % Plotting on the UIAxes in red
            stem(app.UIAxes, y, 'r', 'LineWidth', 2);
            
            % Set labels and title for the UIAxes
            xlabel(app.UIAxes, 'Green:Shadaj, Red:random');
            ylabel(app.UIAxes, 'Frequency in Hz');
            title(app.UIAxes, 'Frequency Comparison');
            
            % Enable the grid on the UIAxes
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');

        end

        % Value changed function: InstructionsTextArea
        function InstructionsTextAreaValueChanged(app, event)
            value = app.InstructionsTextArea.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Frequency')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.GenerateShadajButton = uibutton(app.UIFigure, 'push');
            app.GenerateShadajButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateShadajButtonPushed, true);
            app.GenerateShadajButton.Text = 'Generate Shadaj';
            app.GeneratearandomnoteButton = uibutton(app.UIFigure, 'push');
            app.GeneratearandomnoteButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratearandomnoteButtonPushed, true);
            app.GeneratearandomnoteButton.Text = 'Generate a random note';
            app.PlayShadajButton = uibutton(app.UIFigure, 'push');
            app.PlayShadajButton.ButtonPushedFcn = createCallbackFcn(app, @PlayShadajButtonPushed, true);
            app.PlayShadajButton.Text = 'Play Shadaj';
            app.PlayRandomnoteButton = uibutton(app.UIFigure, 'push');
            app.PlayRandomnoteButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRandomnoteButtonPushed, true);
            app.PlayRandomnoteButton.Text = 'Play Random note';
            app.BaseFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.BaseFrequencyEditFieldLabel.Text = 'Base Frequency';
            app.BaseFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.DisplaynameofnoteButton = uibutton(app.UIFigure, 'push');
            app.DisplaynameofnoteButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaynameofnoteButtonPushed, true);
            app.DisplaynameofnoteButton.Text = 'Display name of note';
            app.DisplaysaptakButton = uibutton(app.UIFigure, 'push');
            app.DisplaysaptakButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaysaptakButtonPushed, true);
            app.DisplaysaptakButton.Text = 'Display saptak';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.InstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.UIFigure);
            app.InstructionsTextArea.ValueChangedFcn = createCallbackFcn(app, @InstructionsTextAreaValueChanged, true);
            app.InstructionsTextArea.Value = {'1) Reference Note : Shadaj'; '2) Click generate random'; '3) click play random'; '4) keep guessing!!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end