classdef Copy_of_Assignment_7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MtButton                 matlab.ui.control.Button
        gtButton                 matlab.ui.control.Button
        rtButton                 matlab.ui.control.Button
        PtButton                 matlab.ui.control.Button
        mtButton                 matlab.ui.control.Button
        GtButton                 matlab.ui.control.Button
        RtButton                 matlab.ui.control.Button
        StButton                 matlab.ui.control.Button
        nmButton                 matlab.ui.control.Button
        dmButton                 matlab.ui.control.Button
        NmButton                 matlab.ui.control.Button
        DmButton                 matlab.ui.control.Button
        PmButton                 matlab.ui.control.Button
        GenerateNotesButton      matlab.ui.control.Button
        nButton                  matlab.ui.control.Button
        dButton                  matlab.ui.control.Button
        mButton                  matlab.ui.control.Button
        gButton                  matlab.ui.control.Button
        rButton                  matlab.ui.control.Button
        NDropDown_2              matlab.ui.control.DropDown
        NDropDown_2Label         matlab.ui.control.Label
        DDropDown_2              matlab.ui.control.DropDown
        DDropDown_2Label         matlab.ui.control.Label
        MDropDown_2              matlab.ui.control.DropDown
        MDropDown_2Label         matlab.ui.control.Label
        GDropDown_2              matlab.ui.control.DropDown
        GDropDown_2Label         matlab.ui.control.Label
        RDropDown_2              matlab.ui.control.DropDown
        RDropDown_2Label         matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        FrequencyLabel           matlab.ui.control.Label
        SelectNoteDropDown       matlab.ui.control.DropDown
        SelectNoteDropDownLabel  matlab.ui.control.Label
        nDropDown                matlab.ui.control.DropDown
        nDropDownLabel           matlab.ui.control.Label
        dDropDown                matlab.ui.control.DropDown
        dDropDownLabel           matlab.ui.control.Label
        mDropDown                matlab.ui.control.DropDown
        mDropDownLabel           matlab.ui.control.Label
        gDropDown                matlab.ui.control.DropDown
        gDropDownLabel           matlab.ui.control.Label
        rDropDown                matlab.ui.control.DropDown
        rDropDownLabel           matlab.ui.control.Label
        NButton                  matlab.ui.control.Button
        DButton                  matlab.ui.control.Button
        PButton                  matlab.ui.control.Button
        MButton                  matlab.ui.control.Button
        GButton                  matlab.ui.control.Button
        RButton                  matlab.ui.control.Button
        SButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectNoteDropDown
        function SelectNoteDropDownValueChanged(app, event)
            value = app.SelectNoteDropDown.Value;
            f=0;
            switch value
                case "C₃/Safed 1"
                    f=130.81;
                case "C#₃/Dᵇ₃/Kali 1"
                    f=138.59;
                case "D₃/Safed 2"
                    f=146.83;
                case "D#₃/Eᵇ₃/Kali 2"
                    f=155.56;
                case "E₃/Safed 3"
                    f=164.81;
                case "F₃/Safed 4"
                    f=174.61;
                case "F#₃/Gᵇ₃/Kali 3"
                    f=185;
                case "G₃/Safed 5"
                    f=196;
                case "G#₃/Aᵇ₃/Kali 4"
                    f=207.65;
                case "A₃/Safed 6"
                    f=220;
                case "A#₃/Bᵇ₃/Kali 5"
                    f=233.08;
                case "B₃/Safed 7"
                    f=246.94;
            end
            app.Label.Text = num2str(f);
        end

        % Button pushed function: GenerateNotesButton
        function GenerateNotesButtonPushed(app, event)
            global S r R g G m M P d D n N Pm dm Dm nm Nm St rt Rt gt Gt mt Mt Pt;
            selectedValue = app.SelectNoteDropDown.Value;
            f=0;
            switch selectedValue
                case "C₃/Safed 1"
                    f=130.81;
                case "C#₃/Dᵇ₃/Kali 1"
                    f=138.59;
                case "D₃/Safed 2"
                    f=146.83;
                case "D#₃/Eᵇ₃/Kali 2"
                    f=155.56;
                case "E₃/Safed 3"
                    f=164.81;
                case "F₃/Safed 4"
                    f=174.61;
                case "F#₃/Gᵇ₃/Kali 3"
                    f=185;
                case "G₃/Safed 5"
                    f=196;
                case "G#₃/Aᵇ₃/Kali 4"
                    f=207.65;
                case "A₃/Safed 6"
                    f=220;
                case "A#₃/Bᵇ₃/Kali 5"
                    f=233.08;
                case "B₃/Safed 7"
                    f=246.94;
            end
            f=f/2; T=1;
            S = note22(2,1,T,f);
            St = note22(3,1,T,f);
            rValue = app.rDropDown.Value;
            switch rValue
                case "r1"
                    r = note22(2,2,T,f);
                    rt = note22(3,2,T,f);
                case "r2"
                    r = note22(2,3,T,f);
                    rt = note22(3,3,T,f);
            end
            RValue = app.RDropDown_2.Value;
            switch RValue
                case "R1"
                    R = note22(2,4,T,f);
                    Rt = note22(3,4,T,f);
                case "R2"
                    R = note22(2,5,T,f);
                    Rt = note22(3,5,T,f);
            end
            gValue = app.gDropDown.Value;
            switch gValue
                case "g1"
                    g = note22(2,6,T,f);
                    gt = note22(3,6,T,f);
                case "g2"
                    g = note22(2,7,T,f);
                    gt = note22(3,7,T,f);
            end
            GValue = app.GDropDown_2.Value;
            switch GValue
                case "G1"
                    G = note22(2,8,T,f);
                    Gt = note22(3,8,T,f);
                case "G2"
                    G = note22(2,9,T,f);
                    Gt = note22(3,9,T,f);
            end
            mValue = app.mDropDown.Value;
            switch mValue
                case "m1"
                    m = note22(2,10,T,f);
                    mt = note22(3,10,T,f);
                case "m2"
                    m = note22(2,11,T,f);
                    mt = note22(4,11,T,f);
            end
            MValue = app.MDropDown_2.Value;
            switch MValue
                case "M1"
                    M = note22(2,12,T,f);
                    Mt = note22(3,12,T,f);
                case "M2"
                    M = note22(2,13,T,f);
                    Mt = note22(3,13,T,f);
            end
            P = note22(2,14,T,f);
            Pm = note22(1,14,T,f);
            Pt = note22(3,14,T,f);
            dValue = app.dDropDown.Value;
            switch dValue
                case "d1"
                    d = note22(2,15,T,f);
                    dm = note22(1,15,T,f);
                case "d2"
                    d = note22(2,16,T,f);
                    dm = note22(1,16,T,f);
            end
            DValue = app.DDropDown_2.Value;
            switch DValue
                case "D1"
                    D = note22(2,17,T,f);
                    Dm = note22(1,17,T,f);
                case "D2"
                    D = note22(2,18,T,f);
                    Dm = note22(1,18,T,f);
            end
            nValue = app.nDropDown.Value;
            switch nValue
                case "n1"
                    n = note22(2,19,T,f);
                    nm = note22(1,19,T,f);
                case "n2"
                    n = note22(2,20,T,f);
                    nm = note22(1,20,T,f);
            end
            NValue = app.NDropDown_2.Value;
            switch NValue
                case "N1"
                    N = note22(2,21,T,f);
                    Nm = note22(1,21,T,f);
                case "N2"
                    N = note22(2,22,T,f);
                    Nm = note22(1,22,T,f);
            end

        end

        % Button pushed function: rButton
        function rButtonPushed(app, event)
            global r;
            sound(r,22050);
        end

        % Button pushed function: gButton
        function gButtonPushed(app, event)
            global g;
            sound(g,22050);
        end

        % Button pushed function: mButton
        function mButtonPushed(app, event)
            global m;
            sound(m,22050);
        end

        % Button pushed function: dButton
        function dButtonPushed(app, event)
            global d;
            sound(d,22050);
        end

        % Button pushed function: nButton
        function nButtonPushed(app, event)
            global n;
            sound(n,22050);
        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            global S;
            sound(S,22050);
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            global R;
            sound(R,22050);
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
            global G;
            sound(G,22050);
        end

        % Button pushed function: MButton
        function MButtonPushed(app, event)
            global M;
            sound(M,22050);
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            global P;
            sound(P,22050);
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            global D;
            sound(D,22050);
        end

        % Button pushed function: NButton
        function NButtonPushed(app, event)
            global N;
            sound(N,22050);
        end

        % Button pushed function: PmButton
        function PmButtonPushed(app, event)
            global Pm;
            sound(Pm,22050);
        end

        % Button pushed function: dmButton
        function dmButtonPushed(app, event)
            global dm;
            sound(dm,22050);
        end

        % Button pushed function: DmButton
        function DmButtonPushed(app, event)
            global Dm;
            sound(Dm,22050);
        end

        % Button pushed function: nmButton
        function nmButtonPushed(app, event)
            global nm;
            sound(nm,22050);
        end

        % Button pushed function: NmButton
        function NmButtonPushed(app, event)
            global Nm;
            sound(Nm,22050);
        end

        % Button pushed function: StButton
        function StButtonPushed(app, event)
            global St;
            sound(St,22050);
        end

        % Button pushed function: rtButton
        function rtButtonPushed(app, event)
            global rt;
            sound(rt,22050);
        end

        % Button pushed function: RtButton
        function RtButtonPushed(app, event)
            global Rt;
            sound(Rt,22050);
        end

        % Button pushed function: gtButton
        function gtButtonPushed(app, event)
            global gt;
            sound(gt,22050);
        end

        % Button pushed function: GtButton
        function GtButtonPushed(app, event)
            global Gt;
            sound(Gt,22050);
        end

        % Button pushed function: mtButton
        function mtButtonPushed(app, event)
            global mt;
            sound(mt,22050);
        end

        % Button pushed function: MtButton
        function MtButtonPushed(app, event)
            global Mt;
            sound(Mt,22050);
        end

        % Button pushed function: PtButton
        function PtButtonPushed(app, event)
            global Pt;
            sound(Pt,22050);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SButton = uibutton(app.UIFigure, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S';
            app.RButton = uibutton(app.UIFigure, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.GButton = uibutton(app.UIFigure, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G';
            app.MButton = uibutton(app.UIFigure, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.MButton.Text = 'M';
            app.PButton = uibutton(app.UIFigure, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = 'P';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.NButton = uibutton(app.UIFigure, 'push');
            app.NButton.ButtonPushedFcn = createCallbackFcn(app, @NButtonPushed, true);
            app.NButton.Text = 'N';
            app.rDropDownLabel = uilabel(app.UIFigure);
            app.rDropDownLabel.Text = 'r';
            app.rDropDown = uidropdown(app.UIFigure);
            app.rDropDown.Items = {'r1', 'r2'};
            app.rDropDown.Value = 'r1';
            app.gDropDownLabel = uilabel(app.UIFigure);
            app.gDropDownLabel.Text = 'g';
            app.gDropDown = uidropdown(app.UIFigure);
            app.gDropDown.Items = {'g1', 'g2'};
            app.gDropDown.Value = 'g1';
            app.mDropDownLabel = uilabel(app.UIFigure);
            app.mDropDownLabel.Text = 'm';
            app.mDropDown = uidropdown(app.UIFigure);
            app.mDropDown.Items = {'m1', 'm2'};
            app.mDropDown.Value = 'm1';
            app.dDropDownLabel = uilabel(app.UIFigure);
            app.dDropDownLabel.Text = 'd';
            app.dDropDown = uidropdown(app.UIFigure);
            app.dDropDown.Items = {'d1', 'd2'};
            app.dDropDown.Value = 'd1';
            app.nDropDownLabel = uilabel(app.UIFigure);
            app.nDropDownLabel.Text = 'n';
            app.nDropDown = uidropdown(app.UIFigure);
            app.nDropDown.Items = {'n1', 'n2'};
            app.nDropDown.Value = 'n1';
            app.SelectNoteDropDownLabel = uilabel(app.UIFigure);
            app.SelectNoteDropDownLabel.Text = 'Select Note';
            app.SelectNoteDropDown = uidropdown(app.UIFigure);
            app.SelectNoteDropDown.Items = {'C₃/Safed 1', 'C#₃/Dᵇ₃/Kali 1', 'D₃/Safed 2', 'D#₃/Eᵇ₃/Kali 2', 'E₃/Safed 3', 'F₃/Safed 4', 'F#₃/Gᵇ₃/Kali 3', 'G₃/Safed 5', 'G#₃/Aᵇ₃/Kali 4', 'A₃/Safed 6', 'A#₃/Bᵇ₃/Kali 5', 'B₃/Safed 7'};
            app.SelectNoteDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectNoteDropDownValueChanged, true);
            app.SelectNoteDropDown.Value = 'C₃/Safed 1';
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '130.81';
            app.RDropDown_2Label = uilabel(app.UIFigure);
            app.RDropDown_2Label.Text = 'R';
            app.RDropDown_2 = uidropdown(app.UIFigure);
            app.RDropDown_2.Items = {'R1', 'R2'};
            app.RDropDown_2.Value = 'R1';
            app.GDropDown_2Label = uilabel(app.UIFigure);
            app.GDropDown_2Label.Text = 'G';
            app.GDropDown_2 = uidropdown(app.UIFigure);
            app.GDropDown_2.Items = {'G1', 'G2'};
            app.GDropDown_2.Value = 'G1';
            app.MDropDown_2Label = uilabel(app.UIFigure);
            app.MDropDown_2Label.Text = 'M';
            app.MDropDown_2 = uidropdown(app.UIFigure);
            app.MDropDown_2.Items = {'M1', 'M2'};
            app.MDropDown_2.Value = 'M1';
            app.DDropDown_2Label = uilabel(app.UIFigure);
            app.DDropDown_2Label.Text = 'D';
            app.DDropDown_2 = uidropdown(app.UIFigure);
            app.DDropDown_2.Items = {'D1', 'D2'};
            app.DDropDown_2.Value = 'D1';
            app.NDropDown_2Label = uilabel(app.UIFigure);
            app.NDropDown_2Label.Text = 'N';
            app.NDropDown_2 = uidropdown(app.UIFigure);
            app.NDropDown_2.Items = {'N1', 'N2'};
            app.NDropDown_2.Value = 'N1';
            app.rButton = uibutton(app.UIFigure, 'push');
            app.rButton.ButtonPushedFcn = createCallbackFcn(app, @rButtonPushed, true);
            app.rButton.Text = 'r';
            app.gButton = uibutton(app.UIFigure, 'push');
            app.gButton.ButtonPushedFcn = createCallbackFcn(app, @gButtonPushed, true);
            app.gButton.Text = 'g';
            app.mButton = uibutton(app.UIFigure, 'push');
            app.mButton.ButtonPushedFcn = createCallbackFcn(app, @mButtonPushed, true);
            app.mButton.Text = 'm';
            app.dButton = uibutton(app.UIFigure, 'push');
            app.dButton.ButtonPushedFcn = createCallbackFcn(app, @dButtonPushed, true);
            app.dButton.Text = 'd';
            app.nButton = uibutton(app.UIFigure, 'push');
            app.nButton.ButtonPushedFcn = createCallbackFcn(app, @nButtonPushed, true);
            app.nButton.Text = 'n';
            app.GenerateNotesButton = uibutton(app.UIFigure, 'push');
            app.GenerateNotesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNotesButtonPushed, true);
            app.GenerateNotesButton.Text = 'Generate Notes';
            app.PmButton = uibutton(app.UIFigure, 'push');
            app.PmButton.ButtonPushedFcn = createCallbackFcn(app, @PmButtonPushed, true);
            app.PmButton.Text = 'Pm';
            app.DmButton = uibutton(app.UIFigure, 'push');
            app.DmButton.ButtonPushedFcn = createCallbackFcn(app, @DmButtonPushed, true);
            app.DmButton.Text = 'Dm';
            app.NmButton = uibutton(app.UIFigure, 'push');
            app.NmButton.ButtonPushedFcn = createCallbackFcn(app, @NmButtonPushed, true);
            app.NmButton.Text = 'Nm';
            app.dmButton = uibutton(app.UIFigure, 'push');
            app.dmButton.ButtonPushedFcn = createCallbackFcn(app, @dmButtonPushed, true);
            app.dmButton.Text = 'dm';
            app.nmButton = uibutton(app.UIFigure, 'push');
            app.nmButton.ButtonPushedFcn = createCallbackFcn(app, @nmButtonPushed, true);
            app.nmButton.Text = 'nm';
            app.StButton = uibutton(app.UIFigure, 'push');
            app.StButton.ButtonPushedFcn = createCallbackFcn(app, @StButtonPushed, true);
            app.StButton.Text = 'St';
            app.RtButton = uibutton(app.UIFigure, 'push');
            app.RtButton.ButtonPushedFcn = createCallbackFcn(app, @RtButtonPushed, true);
            app.RtButton.Text = 'Rt';
            app.GtButton = uibutton(app.UIFigure, 'push');
            app.GtButton.ButtonPushedFcn = createCallbackFcn(app, @GtButtonPushed, true);
            app.GtButton.Text = 'Gt';
            app.mtButton = uibutton(app.UIFigure, 'push');
            app.mtButton.ButtonPushedFcn = createCallbackFcn(app, @mtButtonPushed, true);
            app.mtButton.Text = 'mt';
            app.PtButton = uibutton(app.UIFigure, 'push');
            app.PtButton.ButtonPushedFcn = createCallbackFcn(app, @PtButtonPushed, true);
            app.PtButton.Text = 'Pt';
            app.rtButton = uibutton(app.UIFigure, 'push');
            app.rtButton.ButtonPushedFcn = createCallbackFcn(app, @rtButtonPushed, true);
            app.rtButton.Text = 'rt';
            app.gtButton = uibutton(app.UIFigure, 'push');
            app.gtButton.ButtonPushedFcn = createCallbackFcn(app, @gtButtonPushed, true);
            app.gtButton.Text = 'gt';
            app.MtButton = uibutton(app.UIFigure, 'push');
            app.MtButton.ButtonPushedFcn = createCallbackFcn(app, @MtButtonPushed, true);
            app.MtButton.Text = 'Mt';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_Assignment_7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end