classdef surti_22 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BaseFrequencyLabelKnob  matlab.ui.control.Knob
        BaseFrequencyLabel      matlab.ui.control.Label
        surtiDropDown           matlab.ui.control.DropDown
        surtiDropDownLabel      matlab.ui.control.Label
        NoteDropDown            matlab.ui.control.DropDown
        NoteDropDownLabel       matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                app.NoteDropDown.Items = {'Sa', 'Re', 'Ga', 'Ma', 'Pa', 'Dha', 'Ni'};
                app.surtiDropDown.Visible = 'off'; % Keep hidden until a note is picked
        end

        % Value changed function: NoteDropDown
        function NoteDropDownValueChanged(app, event)
            global selectedNote;
            selectedNote = app.NoteDropDown.Value;
                switch selectedNote
                    case {'Sa', 'Pa'}
                        app.surtiDropDown.Visible = 'off';  % Hide for fixed notes
            
                    case 'Ma'
                        app.surtiDropDown.Visible = 'on';
                        app.surtiDropDown.Items = { ...
                            'Shuddh Madhyam', ...
                            'Ek Shruti Madhyam', ...
                            'Tevra Madhyam', ...
                            'Tevratam Madhyam'};
            
                    otherwise  % Re, Ga, Dha, Ni
                        app.surtiDropDown.Visible = 'on';
                        app.surtiDropDown.Items = { ...
                            'Ati Komal', ...
                            'Komal', ...
                            'Shuddh', ...
                            'Teevra'};
                end
        end

        % Value changed function: surtiDropDown
        function surtiDropDownValueChanged(app, event)
            choosenSurti = app.surtiDropDown.Value;
            global selectedNote
            option = choosenSurti;
            note = selectedNote;
            global shrutiNo
                switch note
                    case 'Sa'
                        shrutiNo = 1;
            
                    case 'Re'
                        if strcmp(option, 'Ati Komal')
                            shrutiNo = 2;
                        elseif strcmp(option, 'Komal')
                            shrutiNo = 3;
                        elseif strcmp(option, 'Shuddh')
                            shrutiNo = 4;
                        elseif strcmp(option, 'Teevra')
                            shrutiNo = 5;
                        end
            
                    case 'Ga'
                        if strcmp(option, 'Ati Komal')
                            shrutiNo = 6;
                        elseif strcmp(option, 'Komal')
                            shrutiNo = 7;
                        elseif strcmp(option, 'Shuddh')
                            shrutiNo = 8;
                        elseif strcmp(option, 'Teevra')
                            shrutiNo = 9;
                        end
            
                    case 'Ma'
                        if strcmp(option, 'Shuddh Madhyam')
                            shrutiNo = 10;
                        elseif strcmp(option, 'Ek Shruti Madhyam')
                            shrutiNo = 11;
                        elseif strcmp(option, 'Tevra Madhyam')
                            shrutiNo = 12;
                        elseif strcmp(option, 'Tevratam Madhyam')
                            shrutiNo = 13;
                        end
            
                    case 'Pa'
                        shrutiNo = 14;
            
                    case 'Dha'
                        if strcmp(option, 'Ati Komal')
                            shrutiNo = 15;
                        elseif strcmp(option, 'Komal')
                            shrutiNo = 16;
                        elseif strcmp(option, 'Shuddh')
                            shrutiNo = 17;
                        elseif strcmp(option, 'Teevra')
                            shrutiNo = 18;
                        end
            
                    case 'Ni'
                        if strcmp(option, 'Ati Komal')
                            shrutiNo = 19;
                        elseif strcmp(option, 'Komal')
                            shrutiNo = 20;
                        elseif strcmp(option, 'Shuddh')
                            shrutiNo = 21;
                        elseif strcmp(option, 'Teevra')
                            shrutiNo = 22;
                        end
                end
                display(shrutiNo)
        end

        % Value changed function: BaseFrequencyLabelKnob
        function BaseFrequencyLabelKnobValueChanged(app, event)
            global baseFreq;
            baseFreq = app.BaseFrequencyLabelKnob.Value;  % get the knob value
            app.BaseFrequencyLabel.Text = sprintf('Base Frequency: %.2f Hz', value);  % display it
            %app.BaseFrequency = value;  % optional: store globally if needed            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NoteDropDownLabel = uilabel(app.UIFigure);
            app.NoteDropDownLabel.Text = 'Note';
            app.NoteDropDown = uidropdown(app.UIFigure);
            app.NoteDropDown.ValueChangedFcn = createCallbackFcn(app, @NoteDropDownValueChanged, true);
            app.surtiDropDownLabel = uilabel(app.UIFigure);
            app.surtiDropDownLabel.Text = 'surti';
            app.surtiDropDown = uidropdown(app.UIFigure);
            app.surtiDropDown.ValueChangedFcn = createCallbackFcn(app, @surtiDropDownValueChanged, true);
            app.BaseFrequencyLabel = uilabel(app.UIFigure);
            app.BaseFrequencyLabel.Text = 'BaseFrequencyLabel';
            app.BaseFrequencyLabelKnob = uiknob(app.UIFigure, 'continuous');
            app.BaseFrequencyLabelKnob.Limits = [50 500];
            app.BaseFrequencyLabelKnob.ValueChangedFcn = createCallbackFcn(app, @BaseFrequencyLabelKnobValueChanged, true);
            app.BaseFrequencyLabelKnob.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = surti_22

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end