classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NameEditField              matlab.ui.control.EditField
        NameEditFieldLabel         matlab.ui.control.Label
        SaveButton                 matlab.ui.control.Button
        GenerateNotesButton        matlab.ui.control.Button
        GenerateCompositionButton  matlab.ui.control.Button
        BaseFreqEditField          matlab.ui.control.NumericEditField
        BaseFreqEditFieldLabel     matlab.ui.control.Label
        TempoEditField             matlab.ui.control.NumericEditField
        TempoEditFieldLabel        matlab.ui.control.Label
        RaagDropDown_2             matlab.ui.control.DropDown
        RaagDropDown_2Label        matlab.ui.control.Label
        TaalDropDown               matlab.ui.control.DropDown
        TaalDropDownLabel          matlab.ui.control.Label
    end

    
    % Signals
    properties (Access = private)
        fs = 22050;
        f double
        d4 double %full
        d1 double % quater
        d2 double % half
        d05 double%eighth matra
        % Lower Saptak
        % Variable definitions for the provided MATLAB code snippet:
        
        % --- Variables related to the 'app' structure ---
        % app: A structure likely containing application data and handles to UI elements.
        
        % --- Variables assigned by the 'contour' function ---
        % The 'contour' function appears to be a custom function that takes several
        % inputs and returns two outputs.
        
        % Lower Saptak (Octave below Middle)
        s double
        fS_L double
        s2 double
        fS_L2 double
        s4 double
        fS_L4 double
        s05 double
        fS_L05 double
        r double
        fR_L double
        r2 double
        fR_L2 double
        r4 double
        fR_L4 double
        r05 double
        fR_L05 double
        g double
        fG_L double
        g2 double
        fG_L2 double
        g4 double
        fG_L4 double
        g05 double
        fG_L05 double
        m double
        fM_L double
        m2 double
        fM_L2 double
        m4 double
        fM_L4 double
        m05 double
        fM_L05 double
        p double
        fP_L double
        p2 double
        fP_L2 double
        p4 double
        fP_L4 double
        d_ double
        fD_L double
        d2_ double
        fD_L2 double
        d4_ double
        fD_L4 double
        n double
        fN_L double
        n2 double
        fN_L2 double
        n4 double
        fN_L4 double
        n05 double
        fN_L05 double
        S double
        fS double
        S2 double
        fS2 double
        S4 double
        fS4 double
        S05 double
        fS05 double
        R double
        fR double
        R2 double
        fR2 double
        R4 double
        fR4 double
        R05 double
        fR05 double
        G double
        fG double
        G2 double
        fG2 double
        G4 double
        fG4 double
        G05 double
        fG05 double
        M double
        fM double
        M2 double
        fM2 double
        M4 double
        fM4 double
        M05 double
        fM05 double
        P double
        fP double
        P2 double
        fP2 double
        P4 double
        fP4 double
        P05 double
        fP05 double
        D double
        fD double
        D2 double
        fD2 double
        D4 double
        fD4 double
        D05 double
        fD05 double
        N double
        fN double
        N2 double
        fN2 double
        N4 double
        fN4 double
        St double
        fSt double
        St2 double
        fSt2 double
        St4 double
        fSt4 double
        R_T double
        fR_T double
        R_T2 double
        fR_T2 double
        R_T4 double
        fR_T4 double
        G_T double
        fG_T double
        G_T2 double
        fG_T2 double
        G_T4 double
        fG_T4 double
        M_T double
        fM_T double
        M_T2 double
        fM_T2 double
        M_T4 double
        fM_T4 double
        P_T double
        fP_T double
        P_T2 double
        fP_T2 double
        P_T4 double
        fP_T4 double
        D_T double
        fD_T double
        D_T2 double
        fD_T2 double
        D_T4 double
        fD_T4 double
        N_T double
        fN_T double
        N_T2 double
        fN_T2 double
        N_T4 double
        fN_T4 double
end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: GenerateCompositionButton
        function GenerateCompositionButtonPushed(app, event)
            taal = app.TaalDropDown.Value;
            raag = app.RaagDropDown_2.Value;
            if strcmp(raag, 'Bageshri')
                    if strcmp(taal, 'Teental') % 16 matras
                        % Asthai: G M D N D M G r S, S r G M G r S (9 + 7 = 16)
                        asthai_notes = [app.G, app.M, app.D, app.N, app.D, app.M, app.G, app.r, app.S, ...
                                        app.S, app.r, app.G, app.M, app.G, app.r, app.S];
                        asthai_durations = [app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, app.d2, ...
                                            app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, app.d1];
                        % Antara: M D N St N D M, St N D M G r S2 (7 + 9 = 16)
                        antara_notes = [app.M, app.D, app.N, app.St, app.N, app.D, app.M, ...
                                        app.St, app.N, app.D, app.M, app.G, app.r, app.S2];
                        antara_durations = [app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, ...
                                            app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, app.d2];
                    elseif strcmp(taal, 'Kehrwa') % 8 matras
                        % Asthai: G M D N S (5 + 3 = 8)
                        asthai_notes = [app.G, app.M, app.D, app.N, app.S];
                        asthai_durations = [app.d1, app.d1, app.d1, app.d1, app.d2];
                        % Antara: M D St N S (5 + 3 = 8)
                        antara_notes = [app.M, app.D, app.St, app.N, app.S];
                        antara_durations = [app.d1, app.d1, app.d1, app.d1, app.d2];
                    elseif strcmp(taal, 'Dadra') % 6 matras
                        % Asthai: G M D S (4 + 2 = 6)
                        asthai_notes = [app.G, app.M, app.D, app.S, app.G, app.M, app.D, app.S];
                        asthai_durations = [app.d1, app.d1, app.d1, app.d05, app.G, app.M, app.D, app.S];
                        % Antara: M D St S (4 + 2 = 6)
                        antara_notes = [app.M, app.D, app.St, app.S, app.M, app.D, app.St, app.S];
                        antara_durations = [app.d1, app.d1, app.d1, app.d05, app.d1, app.d1, app.d1, app.d05];
                    else
                        disp(['Unrecognized taal for Bageshri: ', taal]);
                        asthai_notes = [app.S];
                        asthai_durations = [app.d1];
                        antara_notes = [app.S];
                        antara_durations = [app.d1];
                    end
            
                elseif strcmp(raag, 'Yaman')
                    if strcmp(taal, 'Teental') % 16 matras
                        % Asthai: N R G M D N D M (8 + 8 = 16)
                        asthai_notes = [app.N, app.R, app.G, app.M, app.D, app.N, app.D, app.M];
                        asthai_durations = [app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, app.d2];
                        % Antara: M D N S2 N R G M (8 + 8 = 16)
                        antara_notes = [app.M, app.D, app.N, app.S2, app.N, app.R, app.G, app.M];
                        antara_durations = [app.d1, app.d1, app.d1, app.d2, app.d1, app.d1, app.d1, app.d1];
                    elseif strcmp(taal, 'Kehrwa') % 8 matras
                        % Asthai: N R G S (4 + 4 = 8)
                        asthai_notes = [app.N, app.R, app.G, app.S];
                        asthai_durations = [app.d1, app.d1, app.d1, app.d2];
                        % Antara: G M D S2 (4 + 4 = 8)
                        antara_notes = [app.G, app.M, app.D, app.S2];
                        antara_durations = [app.d1, app.d1, app.d1, app.d2];
                    elseif strcmp(taal, 'Dadra') % 6 matras
                        % Asthai: N R G (3 + 3 = 6)
                        asthai_notes = [app.N, app.R, app.G,app.N, app.R, app.G];
                        asthai_durations = [app.d1, app.d1, app.d05, app.d1, app.d1, app.d05];
                        % Antara: M D S2 (3 + 3 = 6)
                        antara_notes = [app.M, app.D, app.S2, app.M, app.D, app.S2];
                        antara_durations = [app.d1, app.d1, app.d05, app.d1, app.d1, app.d05];
                    else
                        disp(['Unrecognized taal for Yaman: ', taal]);
                        asthai_notes = [app.S];
                        asthai_durations = [app.d1];
                        antara_notes = [app.S];
                        antara_durations = [app.d1];
                    end
            
                elseif strcmp(raag, 'Bhairav')
                    if strcmp(taal, 'Teental') % 16 matras
                        % Asthai: S r G M P d_ n S (8 + 8 = 16)
                        asthai_notes = [app.S, app.r, app.G, app.M, app.P, app.d_, app.n, app.S];
                        asthai_durations = [app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, app.d2];
                        % Antara: S n d_ P M G r S (8 + 8 = 16)
                        antara_notes = [app.S, app.n, app.d_, app.P, app.M, app.G, app.r, app.S];
                        antara_durations = [app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, app.d1, app.d2];
                    elseif strcmp(taal, 'Kehrwa') % 8 matras
                        % Asthai: S r G M (4 + 4 = 8)
                        asthai_notes = [app.S, app.r, app.G, app.M];
                        asthai_durations = [app.d1, app.d1, app.d1, app.d2];
                        % Antara: P d_ n S (4 + 4 = 8)
                        antara_notes = [app.P, app.d_, app.n, app.S];
                        antara_durations = [app.d1, app.d1, app.d1, app.d2];
                    elseif strcmp(taal, 'Dadra') % 6 matras
                        % Asthai: S r G (3 + 3 = 6)
                        asthai_notes = [app.S, app.r, app.G, app.S, app.r, app.G];
                        asthai_durations = [app.d1, app.d1, app.d05, app.d1, app.d1, app.d05];
                        % Antara: M P d_ (3 + 3 = 6)
                        antara_notes = [app.M, app.P, app.d_, app.M, app.P, app.d_];
                        antara_durations = [app.d1, app.d1, app.d05,app.d1, app.d1, app.d05];
                    else
                        disp(['Unrecognized taal for Bhairav: ', taal]);
                        asthai_notes = [app.S];
                        asthai_durations = [app.d1];
                        antara_notes = [app.S];
                        antara_durations = [app.d1];
                    end
                else
                    disp(['Unrecognized raag: ', raag]);
                    asthai_notes = [app.S];
                    asthai_durations = [app.d1];
                    antara_notes = [app.S];
                    antara_durations = [app.d1];
                end
            
                % Concatenate notes
                asthai = [];
                for i = 1:length(asthai_notes)
                    asthai = [asthai, asthai_notes(i)];
                end
                antara = [];
                for i = 1:length(antara_notes)
                    antara = [antara, antara_notes(i)];
                end
                 x = [asthai, antara];
                    shift = randi([1, length(x)-1]);  % Random integer from 1 to length(x)-1
                    global x_rotated;
                    x_rotated = circshift(x, -shift);  % Apply random left shift  in this can you add more randomness like in this its not much 
   % % Split into 4 chunks and rotate each randomly
   %              num_chunks = 4;
   %              chunk_size = floor(length(x) / num_chunks);
   %              x_rotated = [];
   % 
   %              for i = 1:num_chunks
   %                  start_idx = (i-1)*chunk_size + 1;
   %                  end_idx = i*chunk_size;
   % 
   %                  % Last chunk takes all remaining
   %                  if i == num_chunks
   %                      end_idx = length(x);
   %                  end
   % 
   %                  chunk = x(start_idx:end_idx);
   %                  shift = randi([0, length(chunk)-1]);
   %                  chunk_rotated = circshift(chunk, -shift);  % Rotate this chunk
   %                  x_rotated = [x_rotated, chunk_rotated];
   %              end

                sound(x_rotated, app.fs);
        end

        % Button pushed function: GenerateNotesButton
        function GenerateNotesButtonPushed(app, event)
            app.f = app.BaseFreqEditField.Value;
            app.d4 = app.TempoEditField.Value;
            app.d1 = app.d4 / 4;
            app.d2 = app.d4 / 2;
            app.d05 = app.d4 / 8;
            [app.s, app.fS_L] = contour(1, 0, app.d1, app.f);
            [app.s2, app.fS_L] = contour(1, 0, app.d2, app.f);
            [app.s4, app.fS_L4] = contour(1, 0, app.d4, app.f);
            [app.s05, app.fS_L05] = contour(1, 0, app.d05, app.f);
            [app.r, app.fR_L] = contour(1, 2, app.d1, app.f);
            [app.r2, app.fR_L2] = contour(1, 2, app.d2, app.f);
            [app.r4, app.fR_L4] = contour(1, 2, app.d4, app.f);
            [app.r05, app.fR_L05] = contour(1, 2, app.d05, app.f);
            [app.g, app.fG_L] = contour(1, 4, app.d1, app.f);
            [app.g2, app.fG_L2] = contour(1, 4, app.d2, app.f);
            [app.g4, app.fG_L4] = contour(1, 4, app.d4, app.f);
            [app.g05, app.fG_L05] = contour(1, 4, app.d05, app.f);
            [app.m, app.fM_L] = contour(1, 5, app.d1, app.f);
            [app.m2, app.fM_L2] = contour(1, 5, app.d2, app.f);
            [app.m4, app.fM_L4] = contour(1, 5, app.d4, app.f);
            [app.m05, app.fM_L05] = contour(1, 5, app.d05, app.f);
            [app.p, app.fP_L] = contour(1, 7, app.d1, app.f);
            [app.p2, app.fP_L2] = contour(1, 7, app.d2, app.f);
            [app.p4, app.fP_L4] = contour(1, 7, app.d4, app.f);
            [app.d_, app.fD_L] = contour(1, 9, app.d1, app.f);
            [app.d2_, app.fD_L2] = contour(1, 9, app.d2, app.f);
            [app.d4_, app.fD_L4] = contour(1, 9, app.d4, app.f);
            [app.n, app.fN_L] = contour(1, 11, app.d1, app.f);
            [app.n2, app.fN_L2] = contour(1, 11, app.d2, app.f);
            [app.n4, app.fN_L4] = contour(1, 11, app.d4, app.f);
            [app.n05, app.fN_L05] = contour(1, 11, app.d05, app.f);
             
            % Middle Saptak
            [app.S, app.fS] = contour(2, 0, app.d1, app.f);
            [app.S2, app.fS2] = contour(2, 0, app.d2, app.f);
            [app.S4, app.fS4] = contour(2, 0, app.d4, app.f);
            [app.S05, app.fS05] = contour(2, 0, app.d05, app.f);
            [app.R, app.fR] = contour(2, 2, app.d1, app.f);
            [app.R2, app.fR2] = contour(2, 2, app.d2, app.f);
            [app.R4, app.fR4] = contour(2, 2, app.d4, app.f);
            [app.R05, app.fR05] = contour(2, 2, app.d05, app.f);
            [app.G, app.fG] = contour(2, 4, app.d1, app.f);
            [app.G2, app.fG2] = contour(2, 4, app.d2, app.f);
            [app.G4, app.fG4] = contour(2, 4, app.d4, app.f);
            [app.G05, app.fG05] = contour(2, 4, app.d05, app.f);
            [app.M, app.fM] = contour(2, 5, app.d1, app.f);
            [app.M2, app.fM2] = contour(2, 5, app.d2, app.f);
            [app.M4, app.fM4] = contour(2, 5, app.d4, app.f);
            [app.M05, app.fM05] = contour(2, 5, app.d05, app.f);
            [app.P, app.fP] = contour(2, 7, app.d1, app.f);
            [app.P2, app.fP2] = contour(2, 7, app.d2, app.f);
            [app.P4, app.fP4] = contour(2, 7, app.d4, app.f);
            [app.P05, app.fP05] = contour(2, 7, app.d05, app.f);
            [app.D, app.fD] = contour(2, 9, app.d1, app.f);
            [app.D2, app.fD2] = contour(2, 9, app.d2, app.f);
            [app.D4, app.fD4] = contour(2, 9, app.d4, app.f);
            [app.D05, app.fD05] = contour(2, 9, app.d05, app.f);
            [app.N, app.fN] = contour(2, 11, app.d1, app.f);
            [app.N2, app.fN2] = contour(2, 11, app.d2, app.f);
            [app.N4, app.fN4] = contour(2, 11, app.d4, app.f);
            % Higher Saptak
            [app.St, app.fSt] = contour(3, 0, app.d1, app.f);
            [app.St2, app.fSt2] = contour(3, 0, app.d2, app.f);
            [app.St4, app.fSt4] = contour(3, 0, app.d4, app.f);
            [app.R_T, app.fR_T] = contour(3, 2, app.d1, app.f);
            [app.R_T2, app.fR_T2] = contour(3, 2, app.d2, app.f);
            [app.R_T4, app.fR_T4] = contour(3, 2, app.d4, app.f);
            [app.G_T, app.fG_T] = contour(3, 4, app.d1, app.f);
            [app.G_T2, app.fG_T2] = contour(3, 4, app.d2, app.f);
            [app.G_T4, app.fG_T4] = contour(3, 4, app.d4, app.f);
            [app.M_T, app.fM_T] = contour(3, 5, app.d1, app.f);
            [app.M_T2, app.fM_T2] = contour(3, 5, app.d2, app.f);
            [app.M_T4, app.fM_T4] = contour(3, 5, app.d4, app.f);
            [app.P_T, app.fP_T] = contour(3, 7, app.d1, app.f);
            [app.P_T2, app.fP_T2] = contour(3, 7, app.d2, app.f);
            [app.P_T4, app.fP_T4] = contour(3, 7, app.d4, app.f);
            [app.D_T, app.fD_T] = contour(3, 9, app.d1, app.f);
            [app.D_T2, app.fD_T2] = contour(3, 9, app.d2, app.f);
            [app.D_T4, app.fD_T4] = contour(3, 9, app.d4, app.f);
            [app.N_T, app.fN_T] = contour(3, 11, app.d1, app.f);
            [app.N_T2, app.fN_T2] = contour(3, 11, app.d2, app.f);
            [app.N_T4, app.fN_T4] = contour(3, 11, app.d4, app.f);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
    % Get the filename from the 'Name' edit field
    filename = app.NameEditField.Value;
    global x_rotated;
    directory = 'C:\Users\jay_s\OneDrive\Ahmedabad University\mus\assignment1\Music-through-programming\assignment9';
    % Check if the field is empty
    if isempty(filename)
        uialert(app.UIFigure, 'Please enter a filename.', 'Input Error');
        return;
    end

    % Add ".wav" extension if not already present
    if ~endsWith(filename, '.wav')
        filename = [filename, '.wav'];
    end
    
    % Full path to save the file
    fullPath = fullfile(directory, filename);

    % Check if x_rotated and Fs exist
    display(fullPath);
    % Save the audio
    try
        display("try-success")
        audiowrite(fullPath, x_rotated, app.fs);
        uialert(app.UIFigure, ['Audio saved as ', fullPath], 'Success');
    catch ME
        uialert(app.UIFigure, ['Failed to save audio: ', ME.message], 'Error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TaalDropDownLabel = uilabel(app.UIFigure);
            app.TaalDropDownLabel.Text = 'Taal';
            app.TaalDropDown = uidropdown(app.UIFigure);
            app.TaalDropDown.Items = {'Teental', 'Kehrwa', 'Dadra'};
            app.TaalDropDown.Value = 'Teental';
            app.RaagDropDown_2Label = uilabel(app.UIFigure);
            app.RaagDropDown_2Label.Text = 'Raag';
            app.RaagDropDown_2 = uidropdown(app.UIFigure);
            app.RaagDropDown_2.Items = {'Bageshri', 'Bhairav', 'Yaman'};
            app.RaagDropDown_2.Value = 'Bageshri';
            app.TempoEditFieldLabel = uilabel(app.UIFigure);
            app.TempoEditFieldLabel.Text = 'Tempo';
            app.TempoEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseFreqEditFieldLabel = uilabel(app.UIFigure);
            app.BaseFreqEditFieldLabel.Text = 'Base Freq';
            app.BaseFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.GenerateCompositionButton = uibutton(app.UIFigure, 'push');
            app.GenerateCompositionButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCompositionButtonPushed, true);
            app.GenerateCompositionButton.Text = 'Generate Composition ';
            app.GenerateNotesButton = uibutton(app.UIFigure, 'push');
            app.GenerateNotesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNotesButtonPushed, true);
            app.GenerateNotesButton.Text = 'Generate Notes';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end